classdef SMOKEgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SMOKEObfuscateSimulinkModelsUIFigure  matlab.ui.Figure
        LocationButtonGroup            matlab.ui.container.ButtonGroup
        CurrentSubsystemTreeButton     matlab.ui.control.RadioButton
        OnlycurrentSubsystemButton     matlab.ui.control.RadioButton
        CompleteModelButton            matlab.ui.control.RadioButton
        ModeltobeobfuscatedLabel       matlab.ui.control.Label
        RevertChangesButton            matlab.ui.control.Button
        RemoveModelReferencesCheckBox  matlab.ui.control.CheckBox
        RemoveLibraryLinksCheckBox     matlab.ui.control.CheckBox
        OpticalPanel                   matlab.ui.container.Panel
        SquashSubsystemsCheckBox       matlab.ui.control.CheckBox
        GotoFromsTagsCheckBox          matlab.ui.control.CheckBox
        SimulinkFunctionNamesCheckBox  matlab.ui.control.CheckBox
        DataStoreNamesCheckBox         matlab.ui.control.CheckBox
        SignalNamesCheckBox            matlab.ui.control.CheckBox
        BlockNamesCheckBox             matlab.ui.control.CheckBox
        PositioningPanel               matlab.ui.container.Panel
        AutoPositioningCheckBox        matlab.ui.control.CheckBox
        BlockSizesCheckBox             matlab.ui.control.CheckBox
        SubsystemContentPreviewCheckBox  matlab.ui.control.CheckBox
        SubsystemPortLabelsCheckBox    matlab.ui.control.CheckBox
        ModelInformationCheckBox       matlab.ui.control.CheckBox
        DescriptionsCheckBox           matlab.ui.control.CheckBox
        AnnotationsCheckBox            matlab.ui.control.CheckBox
        DocBlocksCheckBox              matlab.ui.control.CheckBox
        ColorsPanel                    matlab.ui.container.Panel
        ColorSwitch                    matlab.ui.control.Switch
        ColourAnnotationsCheckBox      matlab.ui.control.CheckBox
        ColourBlocksCheckBox           matlab.ui.control.CheckBox
        FunctionalPanel                matlab.ui.container.Panel
        ImplementationCheckBox         matlab.ui.control.CheckBox
        SimulinkFunctionsCheckBox      matlab.ui.control.CheckBox
        StateflowPanel                 matlab.ui.container.Panel
        LabelsCheckBox                 matlab.ui.control.CheckBox
        FunctionsCheckBox              matlab.ui.control.CheckBox
        StatesCheckBox                 matlab.ui.control.CheckBox
        BoxesCheckBox                  matlab.ui.control.CheckBox
        ChartsCheckBox                 matlab.ui.control.CheckBox
        StateflowSwitch                matlab.ui.control.Switch
        EventsCheckBox                 matlab.ui.control.CheckBox
        StateflowPortsCheckBox         matlab.ui.control.CheckBox
        SimulinkFunctionArgumentsCheckBox  matlab.ui.control.CheckBox
        MasksCheckBox                  matlab.ui.control.CheckBox
        DialogParametersCheckBox       matlab.ui.control.CheckBox
        BlockCallbacksCheckBox         matlab.ui.control.CheckBox
        ConstantsCheckBox              matlab.ui.control.CheckBox
        RecurseIntoModelReferencesCheckBox  matlab.ui.control.CheckBox
        SelectAllButton                matlab.ui.control.Button
        DeselectAllButton              matlab.ui.control.Button
        ModelNameEditField             matlab.ui.control.EditField
        ObfuscateButton                matlab.ui.control.Button
    end

    
    methods (Access = private)
        function updateCurrentSystem(app)
            opensys = gcs;
            if ~strcmp(opensys, 'simulink') && ~isempty(opensys)
                app.ModelNameEditField.Value = opensys;
                drawnow;
            end               
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updateCurrentSystem(app);
        end

        % Value changed function: ColorSwitch
        function ColorSwitchValueChanged(app, event)
            value = app.ColorSwitch.Value;
            if strcmpi(value, 'On')
                app.ColourBlocksCheckBox.Enable = 'On';
                app.ColourAnnotationsCheckBox.Enable = 'On';
            elseif strcmpi(value, 'Off')
                app.ColourBlocksCheckBox.Enable = 'Off';
                app.ColourAnnotationsCheckBox.Enable = 'Off';
            end
        end

        % Value changed function: StateflowSwitch
        function StateflowSwitchValueChanged(app, event)
            value = app.StateflowSwitch.Value;
            if strcmpi(value, 'On')
                app.ChartsCheckBox.Enable = 'On';
                app.StateflowPortsCheckBox.Enable = 'On';
                app.EventsCheckBox.Enable = 'On';
                app.StatesCheckBox.Enable = 'On';
                app.BoxesCheckBox.Enable = 'On';
                app.FunctionsCheckBox.Enable = 'On';
                app.LabelsCheckBox.Enable = 'On';                
            elseif strcmpi(value, 'Off')
                app.ChartsCheckBox.Enable = 'Off';
                app.StateflowPortsCheckBox.Enable = 'Off';
                app.EventsCheckBox.Enable = 'Off';
                app.StatesCheckBox.Enable = 'Off';
                app.BoxesCheckBox.Enable = 'Off';
                app.FunctionsCheckBox.Enable = 'Off';
                app.LabelsCheckBox.Enable = 'Off';
            end
        end

        % Button pushed function: ObfuscateButton
        function ObfuscateButtonPushed(app, event)
            app.ObfuscateButton.Text = "obfuscating ...";
            updateCurrentSystem(app);
            
            % Model argument
            sys = gcs;
            if isempty(sys)
                uialert(app.SMOKEObfuscateSimulinkModelsUIFigure, 'There is no currently opened or loaded model.', 'Model not found')
                return;
            end
            sys = strtok(sys, '/');
            
            if strcmp(get_param(sys, 'Lock'), 'on')
                input = uiconfirm(app.SMOKEObfuscateSimulinkModelsUIFigure, 'Unlock model?', 'Confirm Unlock');
                if strcmp(input, 'OK')
                    set_param(sys, 'Lock', 'off');
                else
                    return
                end
            end

            %backup the current version of the model is possible now
            app.RevertChangesButton.Enable = "on";

                
            % Build argument list
            if strcmpi(app.ColorSwitch.Value, 'On')
                removecolorblocks = app.ColourBlocksCheckBox.Value;
                removecolorannotations = app.ColourAnnotationsCheckBox.Value;
            else
                removecolorblocks = 0;
                removecolorannotations = 0;
            end
            
            if strcmpi(app.StateflowSwitch.Value, 'On')
                sfcharts = app.ChartsCheckBox.Value;
                sfports  = app.StateflowPortsCheckBox.Value;
                sfevents = app.EventsCheckBox.Value;
                sfstates = app.StatesCheckBox.Value;
                sfboxes  = app.BoxesCheckBox.Value;
                sffunctions = app.FunctionsCheckBox.Value;
                sflabels = app.LabelsCheckBox.Value;
            else
                sfcharts = 0;
                sfports  = 0;
                sfevents = 0;
                sfstates = 0;
                sfboxes  = 0;
                sffunctions = 0;
                sflabels = 0;
            end

            args = {...
                'removemasks',            app.MasksCheckBox.Value, ...
                'removelibrarylinks',     app.RemoveLibraryLinksCheckBox.Value, ...
                'removemodelreferences',  app.RemoveModelReferencesCheckBox.Value, ...
                'removesignalnames',      app.SignalNamesCheckBox.Value, ...
                'removedocblocks',        app.DocBlocksCheckBox.Value, ...
                'removeannotations',      app.AnnotationsCheckBox.Value, ...
                'removedescriptions',     app.DescriptionsCheckBox.Value, ...
                'removeblockcallbacks',   app.BlockCallbacksCheckBox.Value, ...
                'removemodelinformation', app.ModelInformationCheckBox.Value, ...
                'removecolorblocks',      removecolorblocks, ...
                'removecolorannotations', removecolorannotations, ...
                'removedialogparameters', app.DialogParametersCheckBox.Value, ...
                'removepositioning',      app.AutoPositioningCheckBox.Value, ...
                'removesizes',            app.BlockSizesCheckBox.Value, ...
                'renameblocks',           app.BlockNamesCheckBox.Value, ...
                'renameconstants',        app.ConstantsCheckBox.Value, ...
                'renamegotofromtag',      app.GotoFromsTagsCheckBox.Value, ...
                'renamedatastorename',    app.DataStoreNamesCheckBox.Value, ...
                'renamearguments',        app.SimulinkFunctionArgumentsCheckBox.Value, ...
                'removefunctions',        app.SimulinkFunctionsCheckBox.Value, ...
                'renamefunctions',        app.SimulinkFunctionNamesCheckBox.Value, ...
                'hidecontentpreview',     app.SubsystemContentPreviewCheckBox.Value, ...
                'hideportlabels',         app.SubsystemPortLabelsCheckBox.Value, ...
                'sfcharts',               sfcharts, ...
                'sfports',                sfports, ...
                'sfevents',               sfevents, ...
                'sfstates',               sfstates, ...
                'sfboxes',                sfboxes, ...
                'sffunctions',            sffunctions, ...
                'sflabels',               sflabels, ...
                'recursemodels',          app.RecurseIntoModelReferencesCheckBox.Value, ...
                'completeModel',          app.CompleteModelButton.Value, ...
                'recurseSubsystems',      app.CurrentSubsystemTreeButton.Value, ...
                'customdatatypes',        0, ...
                'removeImplement',        app.ImplementationCheckBox.Value, ...
                'squashSubsystems',       app.SquashSubsystemsCheckBox.Value};
            try
                SMOKE(sys, args{:});
            catch ME
                disp("An unexpected error occurred during the obfuscation.")
                disp("Please report this back to the developers of SMOKE giving the following information: ")
                disp("Model file (if shareable), the ME.identifier of this exception and all other exception data.")
                disp("Thank you!")
                rethrow(ME)
            end
            clear global fcnNum;
            app.ObfuscateButton.Text = "Obfuscate";
        end

        % Button pushed function: DeselectAllButton
        function DeselectAllButtonPushed(app, event)
            fieldNames = fieldnames(app);
            allCheckBoxNamesList = fieldNames(contains(fieldNames, 'CheckBox', ...
                'IgnoreCase', false));
            for i = 1:numel(allCheckBoxNamesList)
                if ~strcmp(allCheckBoxNamesList{i}, 'CurrentlySelectedSystemCheckBox') ...
                        && ~strcmp(allCheckBoxNamesList{i}, 'RecurseIntoModelReferencesCheckBox')
                    %if sliders are in 'Off' position, ignore deselection
                    if strcmp(app.ColorSwitch.Value, 'Off') && ismember(app.(allCheckBoxNamesList{i}).Text, ["Blocks" "Annotations "]) || strcmp(app.StateflowSwitch.Value, 'Off') && ismember(app.(allCheckBoxNamesList{i}).Text, ["Charts" "Ports" "Events" "States" "Boxes" "Functions" "Labels"])
                        %do nothing
                    else
                        app.(allCheckBoxNamesList{i}).Value = false;
                    end
                end
            end
            app.RecurseIntoModelReferencesCheckBox.Enable = 'on';
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            fieldNames = fieldnames(app);
            allCheckBoxNamesList = fieldNames(contains(fieldNames, 'CheckBox', ...
                'IgnoreCase', false));
            for i = 1:numel(allCheckBoxNamesList)
                if ~strcmp(allCheckBoxNamesList{i}, 'CurrentlySelectedSystemCheckBox') ...
                        && ~strcmp(allCheckBoxNamesList{i}, 'RecurseIntoModelReferencesCheckBox')
                    %if sliders are in 'Off' position, ignore selection
                    if strcmp(app.ColorSwitch.Value, 'Off') && ismember(app.(allCheckBoxNamesList{i}).Text, ["Blocks" "Annotations "]) || strcmp(app.StateflowSwitch.Value, 'Off') && ismember(app.(allCheckBoxNamesList{i}).Text, ["Charts" "Ports" "Events" "States" "Boxes" "Functions" "Labels"])
                        %do nothing
                    else
                        app.(allCheckBoxNamesList{i}).Value = true;
                    end
                end
            end
            app.RecurseIntoModelReferencesCheckBox.Value = false;
            app.SquashSubsystemsCheckBox.Value = false;
            app.ImplementationCheckBox.Value = false;
            app.RecurseIntoModelReferencesCheckBox.Enable = 'off';
            app.RemoveModelReferencesCheckBox.Enable = 'on';
        end

        % Window button motion function: 
        % SMOKEObfuscateSimulinkModelsUIFigure
        function SMOKEObfuscateSimulinkModelsUIFigureWindowButtonMotion(app, event)
            updateCurrentSystem(app);
        end

        % Value changed function: RemoveModelReferencesCheckBox
        function RemoveModelReferencesCheckBoxValueChanged(app, event)
            checked = app.RemoveModelReferencesCheckBox.Value;
            if checked
                app.RecurseIntoModelReferencesCheckBox.Value = false;
                app.RecurseIntoModelReferencesCheckBox.Enable = 'off';
            else
                app.RecurseIntoModelReferencesCheckBox.Enable = 'on';
            end
        end

        % Value changed function: RecurseIntoModelReferencesCheckBox
        function RecurseIntoModelReferencesCheckBoxValueChanged(app, event)
            checked = app.RecurseIntoModelReferencesCheckBox.Value;
            if checked
                app.RemoveModelReferencesCheckBox.Value = false;
                app.RemoveModelReferencesCheckBox.Enable = 'off';
            else
                app.RemoveModelReferencesCheckBox.Enable = 'on';
            end
        end

        % Button pushed function: RevertChangesButton
        function RevertChangesButtonPushed(app, event)
            model_path = which(bdroot(gcs));
            close_system(bdroot(gcs), 0)
            open_system(model_path)
            app.RevertChangesButton.Enable = "off";
            app.ObfuscateButton.Text = "Obfuscate";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SMOKEObfuscateSimulinkModelsUIFigure = uifigure('Visible', 'off');
            app.SMOKEObfuscateSimulinkModelsUIFigure.Name = 'SMOKE: Obfuscate Simulink Models';
            app.SMOKEObfuscateSimulinkModelsUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @SMOKEObfuscateSimulinkModelsUIFigureWindowButtonMotion, true);
            app.ObfuscateButton = uibutton(app.SMOKEObfuscateSimulinkModelsUIFigure, 'push');
            app.ObfuscateButton.ButtonPushedFcn = createCallbackFcn(app, @ObfuscateButtonPushed, true);
            app.ObfuscateButton.Tooltip = {'Start model obfuscation. WARNING: Saving the file is irreversible.'};
            app.ObfuscateButton.Text = 'Obfuscate';
            app.ModelNameEditField = uieditfield(app.SMOKEObfuscateSimulinkModelsUIFigure, 'text');
            app.ModelNameEditField.Editable = 'off';
            app.ModelNameEditField.Enable = 'off';
            app.ModelNameEditField.Tooltip = {'Open a Simulink model, which you want to obfuscate. The currently opened model will be altered.'};
            app.DeselectAllButton = uibutton(app.SMOKEObfuscateSimulinkModelsUIFigure, 'push');
            app.DeselectAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButtonPushed, true);
            app.DeselectAllButton.Tooltip = {'Deselect all of the options.'};
            app.DeselectAllButton.Text = 'Deselect All';
            app.SelectAllButton = uibutton(app.SMOKEObfuscateSimulinkModelsUIFigure, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Tooltip = {'Select all of the options.'};
            app.SelectAllButton.Text = 'Select All';
            app.RecurseIntoModelReferencesCheckBox = uicheckbox(app.SMOKEObfuscateSimulinkModelsUIFigure);
            app.RecurseIntoModelReferencesCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecurseIntoModelReferencesCheckBoxValueChanged, true);
            app.RecurseIntoModelReferencesCheckBox.Tooltip = {'Apply the obfuscation to all nested model references.'};
            app.RecurseIntoModelReferencesCheckBox.Text = 'Recurse into Model References';
            app.RecurseIntoModelReferencesCheckBox.Value = true;
            app.FunctionalPanel = uipanel(app.SMOKEObfuscateSimulinkModelsUIFigure);
            app.FunctionalPanel.Title = 'Functional';
            app.ConstantsCheckBox = uicheckbox(app.FunctionalPanel);
            app.ConstantsCheckBox.Tooltip = {'Resets Constant blocks to 1 or the empty string.'};
            app.ConstantsCheckBox.Text = 'Constants';
            app.ConstantsCheckBox.Value = true;
            app.BlockCallbacksCheckBox = uicheckbox(app.FunctionalPanel);
            app.BlockCallbacksCheckBox.Tooltip = {'Clear block callback parameters'; ' such as ''LoadFcn'', ''InitFcn'','; ' etc.'};
            app.BlockCallbacksCheckBox.Text = 'Block Callbacks';
            app.BlockCallbacksCheckBox.Value = true;
            app.DialogParametersCheckBox = uicheckbox(app.FunctionalPanel);
            app.DialogParametersCheckBox.Tooltip = {'Resets dialog parameters to their default value. Currently only for built-in blocks.'};
            app.DialogParametersCheckBox.Text = 'Dialog Parameters';
            app.DialogParametersCheckBox.Value = true;
            app.MasksCheckBox = uicheckbox(app.FunctionalPanel);
            app.MasksCheckBox.Tooltip = {'Remove block masks.'};
            app.MasksCheckBox.Text = 'Masks';
            app.MasksCheckBox.Value = true;
            app.SimulinkFunctionArgumentsCheckBox = uicheckbox(app.FunctionalPanel);
            app.SimulinkFunctionArgumentsCheckBox.Tooltip = {'Rename the ''ArgumentName'' parameter of Simulink Function argument in/out ports. Update Simulink Function callers also.'};
            app.SimulinkFunctionArgumentsCheckBox.Text = 'Simulink Function Arguments';
            app.SimulinkFunctionArgumentsCheckBox.Value = true;
            app.StateflowPanel = uipanel(app.FunctionalPanel);
            app.StateflowPanel.Tooltip = {'Rename Stateflow elements to be generic.'};
            app.StateflowPanel.Title = 'Stateflow';
            app.StateflowPortsCheckBox = uicheckbox(app.StateflowPanel);
            app.StateflowPortsCheckBox.Tooltip = {'Rename the ''Name'' parameter of Stateflow inports and outports.'};
            app.StateflowPortsCheckBox.Text = 'Ports';
            app.StateflowPortsCheckBox.Value = true;
            app.EventsCheckBox = uicheckbox(app.StateflowPanel);
            app.EventsCheckBox.Tooltip = {'Rename the ''Name'' parameter of Stateflow events.'};
            app.EventsCheckBox.Text = 'Events';
            app.EventsCheckBox.Value = true;
            app.StateflowSwitch = uiswitch(app.StateflowPanel, 'slider');
            app.StateflowSwitch.ValueChangedFcn = createCallbackFcn(app, @StateflowSwitchValueChanged, true);
            app.StateflowSwitch.Tooltip = {'Enable or disable the renaming of Stateflow elements.'};
            app.StateflowSwitch.Value = 'On';
            app.ChartsCheckBox = uicheckbox(app.StateflowPanel);
            app.ChartsCheckBox.Tooltip = {'Rename the Stateflow chart ''Name'' parameter.'};
            app.ChartsCheckBox.Text = 'Charts';
            app.ChartsCheckBox.Value = true;
            app.BoxesCheckBox = uicheckbox(app.StateflowPanel);
            app.BoxesCheckBox.Tooltip = {'Rename the ''Name'' parameter of Stateflow boxes.'};
            app.BoxesCheckBox.Text = 'Boxes';
            app.BoxesCheckBox.Value = true;
            app.StatesCheckBox = uicheckbox(app.StateflowPanel);
            app.StatesCheckBox.Tooltip = {'Rename the ''Name'' parameter of Stateflow states.'};
            app.StatesCheckBox.Text = 'States';
            app.StatesCheckBox.Value = true;
            app.FunctionsCheckBox = uicheckbox(app.StateflowPanel);
            app.FunctionsCheckBox.Tooltip = {'Removes Stateflow Function names.'};
            app.FunctionsCheckBox.Text = 'Functions';
            app.FunctionsCheckBox.Value = true;
            app.LabelsCheckBox = uicheckbox(app.StateflowPanel);
            app.LabelsCheckBox.Tooltip = {'Shortens Names and resets numbers in Stateflow Transition Labels.'};
            app.LabelsCheckBox.Text = 'Labels';
            app.LabelsCheckBox.Value = true;
            app.SimulinkFunctionsCheckBox = uicheckbox(app.FunctionalPanel);
            app.SimulinkFunctionsCheckBox.Text = 'Simulink Functions';
            app.SimulinkFunctionsCheckBox.Value = true;
            app.ImplementationCheckBox = uicheckbox(app.FunctionalPanel);
            app.ImplementationCheckBox.Tooltip = {'WARNING: THIS CHANGES STRUCTURE. Remove all internals from the current Subsystem, apart from Inports/Outports. If you really want to make some elements are completely removed!'};
            app.ImplementationCheckBox.Text = 'Implementation';
            app.OpticalPanel = uipanel(app.SMOKEObfuscateSimulinkModelsUIFigure);
            app.OpticalPanel.Title = 'Optical';
            app.ColorsPanel = uipanel(app.OpticalPanel);
            app.ColorsPanel.Tooltip = {'Remove colours.'};
            app.ColorsPanel.Title = 'Colors';
            app.ColourBlocksCheckBox = uicheckbox(app.ColorsPanel);
            app.ColourBlocksCheckBox.Tooltip = {'Restore default colors for all blocks.'};
            app.ColourBlocksCheckBox.Text = 'Blocks';
            app.ColourBlocksCheckBox.Value = true;
            app.ColourAnnotationsCheckBox = uicheckbox(app.ColorsPanel);
            app.ColourAnnotationsCheckBox.Tooltip = {'Restore default colors for all annotations.'};
            app.ColourAnnotationsCheckBox.Text = 'Annotations ';
            app.ColourAnnotationsCheckBox.Value = true;
            app.ColorSwitch = uiswitch(app.ColorsPanel, 'slider');
            app.ColorSwitch.ValueChangedFcn = createCallbackFcn(app, @ColorSwitchValueChanged, true);
            app.ColorSwitch.Tooltip = {'Enable or disable the removal of colors.'};
            app.ColorSwitch.Value = 'On';
            app.DocBlocksCheckBox = uicheckbox(app.OpticalPanel);
            app.DocBlocksCheckBox.Tooltip = {'Remove all DocBlocks.'};
            app.DocBlocksCheckBox.Text = 'DocBlocks';
            app.DocBlocksCheckBox.Value = true;
            app.AnnotationsCheckBox = uicheckbox(app.OpticalPanel);
            app.AnnotationsCheckBox.Tooltip = {'Remove all annotations.'};
            app.AnnotationsCheckBox.Text = 'Annotations';
            app.AnnotationsCheckBox.Value = true;
            app.DescriptionsCheckBox = uicheckbox(app.OpticalPanel);
            app.DescriptionsCheckBox.Tooltip = {'Remove descriptive block parameters.'};
            app.DescriptionsCheckBox.Text = 'Descriptions';
            app.DescriptionsCheckBox.Value = true;
            app.ModelInformationCheckBox = uicheckbox(app.OpticalPanel);
            app.ModelInformationCheckBox.Tooltip = {'Clear the information stored in the ''Model Properties''.'};
            app.ModelInformationCheckBox.Text = 'Model Information';
            app.ModelInformationCheckBox.Value = true;
            app.SubsystemPortLabelsCheckBox = uicheckbox(app.OpticalPanel);
            app.SubsystemPortLabelsCheckBox.Tooltip = {'Hide the port labels shown in subsystems.'};
            app.SubsystemPortLabelsCheckBox.Text = 'Subsystem Port Labels';
            app.SubsystemPortLabelsCheckBox.Value = true;
            app.SubsystemContentPreviewCheckBox = uicheckbox(app.OpticalPanel);
            app.SubsystemContentPreviewCheckBox.Tooltip = {'Hide the Content Preview shown in subsystems.'};
            app.SubsystemContentPreviewCheckBox.Text = 'Subsystem Content Preview';
            app.SubsystemContentPreviewCheckBox.Value = true;
            app.PositioningPanel = uipanel(app.OpticalPanel);
            app.PositioningPanel.Title = 'Positioning';
            app.BlockSizesCheckBox = uicheckbox(app.PositioningPanel);
            app.BlockSizesCheckBox.Tooltip = {'Resets all shapes and sizes of blocks to their block default.'};
            app.BlockSizesCheckBox.Text = 'Block Sizes';
            app.BlockSizesCheckBox.Value = true;
            app.AutoPositioningCheckBox = uicheckbox(app.PositioningPanel);
            app.AutoPositioningCheckBox.Tooltip = {'Resets every block''s position to an auto-formatted position.'};
            app.AutoPositioningCheckBox.Text = 'Auto Positioning';
            app.AutoPositioningCheckBox.Value = true;
            app.BlockNamesCheckBox = uicheckbox(app.OpticalPanel);
            app.BlockNamesCheckBox.Tooltip = {'Change the ''Name'' parameters of all blocks to be generic.'};
            app.BlockNamesCheckBox.Text = 'Block Names';
            app.BlockNamesCheckBox.Value = true;
            app.SignalNamesCheckBox = uicheckbox(app.OpticalPanel);
            app.SignalNamesCheckBox.Tooltip = {'Remove signal names.'};
            app.SignalNamesCheckBox.Text = 'Signal Names';
            app.SignalNamesCheckBox.Value = true;
            app.DataStoreNamesCheckBox = uicheckbox(app.OpticalPanel);
            app.DataStoreNamesCheckBox.Tooltip = {'Rename the ''DataStoreName'' parameter of Data Store Memory, Read, and Write blocks.'};
            app.DataStoreNamesCheckBox.Text = 'Data Store Names';
            app.DataStoreNamesCheckBox.Value = true;
            app.SimulinkFunctionNamesCheckBox = uicheckbox(app.OpticalPanel);
            app.SimulinkFunctionNamesCheckBox.Tooltip = {'Rename the ''Function Name'' parameter of all triggers. Update Simulink Function callers also.'};
            app.SimulinkFunctionNamesCheckBox.Text = 'Simulink Function Names';
            app.SimulinkFunctionNamesCheckBox.Value = true;
            app.GotoFromsTagsCheckBox = uicheckbox(app.OpticalPanel);
            app.GotoFromsTagsCheckBox.Tooltip = {'Rename the ''GotoTag'' parameter of Goto and From blocks (the parameter shown inside the  block).'};
            app.GotoFromsTagsCheckBox.Text = 'Goto/Froms Tags';
            app.GotoFromsTagsCheckBox.Value = true;
            app.SquashSubsystemsCheckBox = uicheckbox(app.OpticalPanel);
            app.SquashSubsystemsCheckBox.Tooltip = {'WARNING: THIS CHANGES STRUCTURE. Resolve all Subsystems. A flat model remains.'};
            app.SquashSubsystemsCheckBox.Text = 'Squash Subsystems';
            app.RemoveLibraryLinksCheckBox = uicheckbox(app.SMOKEObfuscateSimulinkModelsUIFigure);
            app.RemoveLibraryLinksCheckBox.Tooltip = {'Remove links to libraries. This includes them in the model directly.'};
            app.RemoveLibraryLinksCheckBox.Text = 'Remove Library Links';
            app.RemoveLibraryLinksCheckBox.Value = true;
            app.RemoveModelReferencesCheckBox = uicheckbox(app.SMOKEObfuscateSimulinkModelsUIFigure);
            app.RemoveModelReferencesCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveModelReferencesCheckBoxValueChanged, true);
            app.RemoveModelReferencesCheckBox.Tooltip = {'Remove references to other models.'};
            app.RemoveModelReferencesCheckBox.Text = 'Remove Model References';
            app.RevertChangesButton = uibutton(app.SMOKEObfuscateSimulinkModelsUIFigure, 'push');
            app.RevertChangesButton.ButtonPushedFcn = createCallbackFcn(app, @RevertChangesButtonPushed, true);
            app.RevertChangesButton.Enable = 'off';
            app.RevertChangesButton.Tooltip = {'Reverse the last changes. Only possible until the model is saved.'};
            app.RevertChangesButton.Text = 'Revert Changes';
            app.ModeltobeobfuscatedLabel = uilabel(app.SMOKEObfuscateSimulinkModelsUIFigure);
            app.ModeltobeobfuscatedLabel.Tooltip = {'Open a Simulink model, which you want to obfuscate. The currently opened model will be altered.'};
            app.ModeltobeobfuscatedLabel.Text = 'Model to be obfuscated:';
            app.LocationButtonGroup = uibuttongroup(app.SMOKEObfuscateSimulinkModelsUIFigure);
            app.LocationButtonGroup.Title = 'Location';
            app.CompleteModelButton = uiradiobutton(app.LocationButtonGroup);
            app.CompleteModelButton.Tooltip = {'The complete model, including all subsystems will be obfuscated with the chosen settings.'};
            app.CompleteModelButton.Text = 'Complete Model';
            app.CompleteModelButton.Value = true;
            app.OnlycurrentSubsystemButton = uiradiobutton(app.LocationButtonGroup);
            app.OnlycurrentSubsystemButton.Tooltip = {'Only the currently visible Subsystem will obfuscated.'};
            app.OnlycurrentSubsystemButton.Text = 'Only current Subsystem';
            app.CurrentSubsystemTreeButton = uiradiobutton(app.LocationButtonGroup);
            app.CurrentSubsystemTreeButton.Tooltip = {'The currently visible Subsystem and all those included in it (also recursively) will be obfuscated.'};
            app.CurrentSubsystemTreeButton.Text = 'Current Subsystem Tree';
            % Show the figure after all components are created
            app.SMOKEObfuscateSimulinkModelsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SMOKEgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SMOKEObfuscateSimulinkModelsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SMOKEObfuscateSimulinkModelsUIFigure)
        end
    end
end